classdef interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DutyCycleEditField              matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel         matlab.ui.control.Label
        InterPulseIntervalEditField     matlab.ui.control.NumericEditField
        InterPulseIntervalEditFieldLabel  matlab.ui.control.Label
        PulseDurationEditField          matlab.ui.control.NumericEditField
        PulseDurationEditFieldLabel     matlab.ui.control.Label
        StimulationModeButtonGroup      matlab.ui.container.ButtonGroup
        PulsedButton                    matlab.ui.control.ToggleButton
        ConstantButton                  matlab.ui.control.ToggleButton
        StimulationParametersLabel      matlab.ui.control.Label
        POSTSTIMsEditField              matlab.ui.control.NumericEditField
        POSTSTIMsEditFieldLabel         matlab.ui.control.Label
        STIMsEditField                  matlab.ui.control.NumericEditField
        STIMsEditFieldLabel             matlab.ui.control.Label
        PRESTIMsEditField               matlab.ui.control.NumericEditField
        PRESTIMsEditFieldLabel          matlab.ui.control.Label
        MAXsEditField                   matlab.ui.control.NumericEditField
        MAXsEditFieldLabel              matlab.ui.control.Label
        MINsEditField                   matlab.ui.control.NumericEditField
        MINsEditFieldLabel              matlab.ui.control.Label
        TrialTimesLabel                 matlab.ui.control.Label
        ITITimesLabel                   matlab.ui.control.Label
        SelectPowersListBox             matlab.ui.control.ListBox
        SelectPowersListBoxLabel        matlab.ui.control.Label
        SelectPositionsListBox          matlab.ui.control.ListBox
        SelectPositionsListBoxLabel     matlab.ui.control.Label
        TrialsperStateEditField         matlab.ui.control.NumericEditField
        TrialsperStateEditFieldLabel    matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        EstimatedExperimentDurationEditField  matlab.ui.control.NumericEditField
        EstimatedExperimentDurationEditFieldLabel  matlab.ui.control.Label
        AverageTrialDurationEditField   matlab.ui.control.NumericEditField
        AverageTrialDurationEditFieldLabel  matlab.ui.control.Label
        TotalCombinationsEditField      matlab.ui.control.NumericEditField
        TotalCombinationsEditFieldLabel  matlab.ui.control.Label
        SummaryLabel                    matlab.ui.control.Label
        ITIEditField_2                  matlab.ui.control.NumericEditField
        ITIEditField_2Label             matlab.ui.control.Label
        PowerEditField_2                matlab.ui.control.NumericEditField
        PowerEditField_2Label           matlab.ui.control.Label
        StimulationPositionEditField_2  matlab.ui.control.NumericEditField
        StimulationPositionEditField_2Label  matlab.ui.control.Label
        NextTrialLabel                  matlab.ui.control.Label
        ITIEditField                    matlab.ui.control.NumericEditField
        ITIEditFieldLabel               matlab.ui.control.Label
        PowerEditField                  matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        StimulationPositionEditField    matlab.ui.control.NumericEditField
        StimulationPositionEditFieldLabel  matlab.ui.control.Label
        CurrentTrialLabel               matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = private)
        
        function results = trigger_update(app)
            app.update_total_combinations;
            app.update_average_trial_duration;
            app.update_estimated_duration;    
        end

        function update_total_combinations(app)
            selected_positions = app.SelectPositionsListBox.Value;
            selected_powers = app.SelectPowersListBox.Value;

            num_positions = length(selected_positions);
            num_powers = length(selected_powers); 
            total_combinations = num_positions * num_powers;

            app.TotalCombinationsEditField.Value = total_combinations + 1;
            % Add 1 for the no stim powers

        end

        function update_average_trial_duration(app)

            min_ITI = app.MINsEditField.Value;
            max_ITI = app.MAXsEditField.Value;
            avg_ITI = mean([min_ITI max_ITI]);

            stim_length = app.PRESTIMsEditField.Value + app.STIMsEditField.Value + app.POSTSTIMsEditField.Value;

            avg_length = round(avg_ITI + stim_length, 1);
            app.AverageTrialDurationEditField.Value = avg_length;
        end

        function update_estimated_duration(app)
            total_time_sec = ...
            app.TotalCombinationsEditField.Value * ...
            app.TrialsperStateEditField.Value * ...
            app.AverageTrialDurationEditField.Value;

            total_time_min = round(total_time_sec/60, 2);
            app.EstimatedExperimentDurationEditField.Value = total_time_min;
        end
        
        function enable_stim_params(app, state)
            app.PulseDurationEditField.Enable = state;
            app.InterPulseIntervalEditField.Enable = state;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function TrialsperStateEditFieldValueChanged(app, event)
            value = app.TrialsperStateEditField.Value;
            app.trigger_update;
        end

        % Value changed function: MAXsEditField, MINsEditField, 
        % ...and 6 other components
        function update_callback(app, event)
            app.trigger_update;
        end

        % Selection changed function: StimulationModeButtonGroup
        function stim_mode_changed(app, event)
            stim_mode_button = app.StimulationModeButtonGroup.SelectedObject;
            disp(strcmp(stim_mode_button.Text, "Pulsed"));
            if strcmp(stim_mode_button.Text, 'Pulsed')
                app.enable_stim_params(true);
            else
                app.enable_stim_params(false);
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {687, 687};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {297, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Freely Moving Optogenetics';
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TrialsperStateEditFieldLabel = uilabel(app.GridLayout2);
            app.TrialsperStateEditFieldLabel.Text = 'Trials per State';
            app.TrialsperStateEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TrialsperStateEditField.Limits = [0 Inf];
            app.TrialsperStateEditField.ValueDisplayFormat = '%.0f';
            app.TrialsperStateEditField.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.TrialsperStateEditField.Value = 20;
            app.SelectPositionsListBoxLabel = uilabel(app.GridLayout2);
            app.SelectPositionsListBoxLabel.Text = 'Select Positions';
            app.SelectPositionsListBox = uilistbox(app.GridLayout2);
            app.SelectPositionsListBox.Items = {'250', '750', '1250'};
            app.SelectPositionsListBox.Multiselect = 'on';
            app.SelectPositionsListBox.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.SelectPositionsListBox.Value = {'250'};
            app.SelectPowersListBoxLabel = uilabel(app.GridLayout2);
            app.SelectPowersListBoxLabel.Text = 'Select Powers';
            app.SelectPowersListBox = uilistbox(app.GridLayout2);
            app.SelectPowersListBox.Items = {'0.5', '1.0', '2.0'};
            app.SelectPowersListBox.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.SelectPowersListBox.Value = '0.5';
            app.ITITimesLabel = uilabel(app.GridLayout2);
            app.ITITimesLabel.Text = 'ITI Times';
            app.TrialTimesLabel = uilabel(app.GridLayout2);
            app.TrialTimesLabel.Text = 'Trial Times';
            app.MINsEditFieldLabel = uilabel(app.GridLayout2);
            app.MINsEditFieldLabel.Text = 'MIN (s)';
            app.MINsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MINsEditField.ValueDisplayFormat = '%.1f s';
            app.MINsEditField.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.MINsEditField.Value = 15;
            app.MAXsEditFieldLabel = uilabel(app.GridLayout2);
            app.MAXsEditFieldLabel.Text = 'MAX (s)';
            app.MAXsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MAXsEditField.ValueDisplayFormat = '%.1f s';
            app.MAXsEditField.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.MAXsEditField.Value = 25;
            app.PRESTIMsEditFieldLabel = uilabel(app.GridLayout2);
            app.PRESTIMsEditFieldLabel.Text = 'PRE STIM (s)';
            app.PRESTIMsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PRESTIMsEditField.Limits = [0 Inf];
            app.PRESTIMsEditField.ValueDisplayFormat = '%.1f s';
            app.PRESTIMsEditField.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.PRESTIMsEditField.Value = 4;
            app.STIMsEditFieldLabel = uilabel(app.GridLayout2);
            app.STIMsEditFieldLabel.Text = 'STIM (s)';
            app.STIMsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.STIMsEditField.Limits = [0 Inf];
            app.STIMsEditField.ValueDisplayFormat = '%.1f s';
            app.STIMsEditField.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.STIMsEditField.Value = 2;
            app.POSTSTIMsEditFieldLabel = uilabel(app.GridLayout2);
            app.POSTSTIMsEditFieldLabel.Text = 'POST STIM (s)';
            app.POSTSTIMsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.POSTSTIMsEditField.Limits = [0 Inf];
            app.POSTSTIMsEditField.ValueDisplayFormat = '%.1f s';
            app.POSTSTIMsEditField.ValueChangedFcn = createCallbackFcn(app, @update_callback, true);
            app.POSTSTIMsEditField.Value = 4;
            app.StimulationParametersLabel = uilabel(app.GridLayout2);
            app.StimulationParametersLabel.Text = 'Stimulation Parameters';
            app.StimulationModeButtonGroup = uibuttongroup(app.GridLayout2);
            app.StimulationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @stim_mode_changed, true);
            app.StimulationModeButtonGroup.Title = 'Stimulation Mode';
            app.ConstantButton = uitogglebutton(app.StimulationModeButtonGroup);
            app.ConstantButton.Text = 'Constant';
            app.ConstantButton.Value = true;
            app.PulsedButton = uitogglebutton(app.StimulationModeButtonGroup);
            app.PulsedButton.Text = 'Pulsed';
            app.PulseDurationEditFieldLabel = uilabel(app.GridLayout2);
            app.PulseDurationEditFieldLabel.Text = 'Pulse Duration';
            app.PulseDurationEditField = uieditfield(app.GridLayout2, 'numeric');
            app.PulseDurationEditField.Limits = [0 Inf];
            app.PulseDurationEditField.ValueDisplayFormat = '%d ms';
            app.PulseDurationEditField.Enable = 'off';
            app.InterPulseIntervalEditFieldLabel = uilabel(app.GridLayout2);
            app.InterPulseIntervalEditFieldLabel.Text = 'Inter Pulse Interval';
            app.InterPulseIntervalEditField = uieditfield(app.GridLayout2, 'numeric');
            app.InterPulseIntervalEditField.Limits = [0 Inf];
            app.InterPulseIntervalEditField.ValueDisplayFormat = '%d ms';
            app.InterPulseIntervalEditField.Enable = 'off';
            app.DutyCycleEditFieldLabel = uilabel(app.GridLayout2);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DutyCycleEditField.Limits = [0 Inf];
            app.DutyCycleEditField.ValueDisplayFormat = '%d ms';
            app.DutyCycleEditField.Enable = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'Current Parameters';
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.CurrentTrialLabel = uilabel(app.GridLayout3);
            app.CurrentTrialLabel.Text = 'Current Trial';
            app.StimulationPositionEditFieldLabel = uilabel(app.GridLayout3);
            app.StimulationPositionEditFieldLabel.Text = 'Stimulation Position';
            app.StimulationPositionEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PowerEditFieldLabel = uilabel(app.GridLayout3);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ITIEditFieldLabel = uilabel(app.GridLayout3);
            app.ITIEditFieldLabel.Text = 'ITI';
            app.ITIEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NextTrialLabel = uilabel(app.GridLayout3);
            app.NextTrialLabel.Text = 'Next Trial';
            app.StimulationPositionEditField_2Label = uilabel(app.GridLayout3);
            app.StimulationPositionEditField_2Label.Text = 'Stimulation Position';
            app.StimulationPositionEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.PowerEditField_2Label = uilabel(app.GridLayout3);
            app.PowerEditField_2Label.Text = 'Power';
            app.PowerEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.ITIEditField_2Label = uilabel(app.GridLayout3);
            app.ITIEditField_2Label.Text = 'ITI';
            app.ITIEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.SummaryLabel = uilabel(app.GridLayout3);
            app.SummaryLabel.Text = 'Summary';
            app.TotalCombinationsEditFieldLabel = uilabel(app.GridLayout3);
            app.TotalCombinationsEditFieldLabel.Text = 'Total Combinations';
            app.TotalCombinationsEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TotalCombinationsEditField.ValueDisplayFormat = '%.0f';
            app.TotalCombinationsEditField.Editable = 'off';
            app.TotalCombinationsEditField.Value = 3;
            app.AverageTrialDurationEditFieldLabel = uilabel(app.GridLayout3);
            app.AverageTrialDurationEditFieldLabel.Text = 'Average Trial Duration';
            app.AverageTrialDurationEditField = uieditfield(app.GridLayout3, 'numeric');
            app.AverageTrialDurationEditField.ValueDisplayFormat = '%.1f s';
            app.AverageTrialDurationEditField.Editable = 'off';
            app.AverageTrialDurationEditField.Value = 30;
            app.EstimatedExperimentDurationEditFieldLabel = uilabel(app.GridLayout3);
            app.EstimatedExperimentDurationEditFieldLabel.Text = 'Estimated Experiment Duration';
            app.EstimatedExperimentDurationEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EstimatedExperimentDurationEditField.ValueDisplayFormat = '%.1f min';
            app.EstimatedExperimentDurationEditField.Editable = 'off';
            app.EstimatedExperimentDurationEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end